classdef Maze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MazeUIFigure              matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridSizeSpinner           matlab.ui.control.Spinner
        GridSizeLabel             matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        ResetButton               matlab.ui.control.Button
        BranchingModeButtonGroup  matlab.ui.container.ButtonGroup
        lastButton                matlab.ui.control.ToggleButton
        midleButton               matlab.ui.control.ToggleButton
        firstButton               matlab.ui.control.ToggleButton
        DisplaysolutionCheckBox   matlab.ui.control.CheckBox
        NewMazeButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slen  % Description
        solplot  % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Λήψη του μεγέθους της οθόνης (Screen Size)
            screenSize = get(0, 'ScreenSize'); % [left, bottom, width, height]
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            % 2. Λήψη του τρέχοντος μεγέθους του παραθύρου της εφαρμογής
            appPosition = app.MazeUIFigure.Position; % [left, bottom, width, height]
            appWidth = appPosition(3);
            appHeight = appPosition(4);
            
            % 3. Υπολογισμός της κεντρικής θέσης
            newLeft = (screenWidth - appWidth) / 2;
            newBottom = (screenHeight - appHeight) / 2;
            
            % 4. Εφαρμογή της νέας θέσης στο παράθυρο
            app.MazeUIFigure.Position = [newLeft, newBottom, appWidth, appHeight];
        end

        % Callback function: BranchingModeButtonGroup, GridSizeSpinner, 
        % ...and 1 other component
        function NewMaze(app, event)
            gridsize = app.GridSizeSpinner.Value;
            bmode = app.BranchingModeButtonGroup.SelectedObject.Text;
            
            [wall,wx,wy,sol,sx,sy] = amaze(gridsize,bmode);
            app.slen = numedges(sol);
            plot(app.UIAxes,wall,XData=wx,YData=wy,NodeLabel=[])
            axis(app.UIAxes,"equal")
            
            hold(app.UIAxes,"on")
            app.solplot = plot(app.UIAxes,sol,XData=sx,YData=sy,NodeLabel=[]);
            hold(app.UIAxes,"off")      
            
            app.solplot.Visible = false;

            app.DisplaysolutionCheckBox.Enable = true;
            DisplaysolutionCheckBoxValueChanged(app,[])
        end

        % Value changed function: DisplaysolutionCheckBox
        function DisplaysolutionCheckBoxValueChanged(app, event)
            value = app.DisplaysolutionCheckBox.Value;
            
            if value
                app.solplot.Visible = true;
                title(app.UIAxes,"length = " + app.slen)
            else
                app.solplot.Visible = false;
                title(app.UIAxes,"Maze")
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes)
            title(app.UIAxes,"Maze")
            app.DisplaysolutionCheckBox.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MazeUIFigure = uifigure('Visible', 'off');
            app.MazeUIFigure.Name = 'Maze';
            app.MazeUIFigure.Icon = fullfile(pathToMLAPP, 'maze.png');
            app.GridLayout = uigridlayout(app.MazeUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Maze')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.NewMazeButton = uibutton(app.GridLayout, 'push');
            app.NewMazeButton.ButtonPushedFcn = createCallbackFcn(app, @NewMaze, true);
            app.NewMazeButton.Icon = fullfile(pathToMLAPP, 'maze.png');
            app.NewMazeButton.Text = 'New Maze';
            app.DisplaysolutionCheckBox = uicheckbox(app.GridLayout);
            app.DisplaysolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplaysolutionCheckBoxValueChanged, true);
            app.DisplaysolutionCheckBox.Text = 'Display solution?';
            app.BranchingModeButtonGroup = uibuttongroup(app.GridLayout);
            app.BranchingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NewMaze, true);
            app.BranchingModeButtonGroup.Title = 'Branching Mode';
            app.firstButton = uitogglebutton(app.BranchingModeButtonGroup);
            app.firstButton.Text = 'first';
            app.firstButton.Value = true;
            app.midleButton = uitogglebutton(app.BranchingModeButtonGroup);
            app.midleButton.Text = 'midle';
            app.lastButton = uitogglebutton(app.BranchingModeButtonGroup);
            app.lastButton.Text = 'last';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'maze_logo.png');
            app.GridSizeLabel = uilabel(app.GridLayout);
            app.GridSizeLabel.Text = 'GridSize';
            app.GridSizeSpinner = uispinner(app.GridLayout);
            app.GridSizeSpinner.Step = 5;
            app.GridSizeSpinner.Limits = [5 30];
            app.GridSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @NewMaze, true);
            app.GridSizeSpinner.Value = 5;
            % Show the figure after all components are created
            app.MazeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MazeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MazeUIFigure)
        end
    end
end